classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        SatelliteImageryChangeDetectionAppLabel  matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout6                matlab.ui.container.GridLayout
        GridLayout8                matlab.ui.container.GridLayout
        NextButton                 matlab.ui.control.Button
        PreviousButton             matlab.ui.control.Button
        GridLayout7                matlab.ui.container.GridLayout
        ResetButton                matlab.ui.control.Button
        TipsButton                 matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        GridLayout3                matlab.ui.container.GridLayout
        GridLayout11               matlab.ui.container.GridLayout
        GridLayout12               matlab.ui.container.GridLayout
        TimeSlider                 matlab.ui.control.Slider
        TimeSliderLabel            matlab.ui.control.Label
        SelectAreaButton           matlab.ui.control.Button
        SmartZoomButton            matlab.ui.control.Button
        InteractiveToolsLabel      matlab.ui.control.Label
        GridLayout10               matlab.ui.container.GridLayout
        DifferenceHighlightButton  matlab.ui.control.Button
        HeatmapButton              matlab.ui.control.Button
        BlendedImageButton         matlab.ui.control.Button
        AlignVisualButton          matlab.ui.control.Button
        VisualizationLabel         matlab.ui.control.Label
        GridLayout4                matlab.ui.container.GridLayout
        ClearCacheButton           matlab.ui.control.Button
        AlignImagesButton          matlab.ui.control.Button
        PreprocessButton           matlab.ui.control.Button
        ImagenameTextArea          matlab.ui.control.TextArea
        SelectImageButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Properties for image navigation
        %getlocation
        CurrentImages           % Cell array of images
        CurrentTimestamps       % Array of timestamps
        CurrentImageIndex       % Current displayed image index
        SelectedFolderPath      % Path to selected folder

        %alignpair
        AlignedImages           % Cell array of aligned images
        AlignedFolderPath       % Path to aligned images folder
        Tforms                  % Transformation matrices
        AlignStatuses           % Alignment status for each image

        %preprocess
        PreprocessedImages      % Cell array of preprocessed images
        PreprocessedFolderPath  % Path to preprocessed images folder

        % New properties for zoom and playback features
        ZoomEnabled = false           % Whether zoom mode is enabled
        PlaybackTimer               % Timer for automatic playback
        PlaybackSpeed = 0           % Playback speed (0 means stopped)

        % Earth globe properties
        EarthSphere             % Handle to earth sphere
        LocationMarkers = []    % Handles to location markers
        RotationTimer          % Timer for earth rotation
        IsGlobeView = true     % Whether showing globe or images

        % Moon properties
        MoonSphere             % Handle to moon sphere
        IsMoonView = false     % Whether showing moon images
        MoonFolderPath = ''    % Path to moon images folder
        MoonImages = {}        % Moon images array
        MoonTimestamps = []    % Moon image timestamps

        % Manual rotation properties
        IsRotating = false
        LastMousePos = []
        
        % Predefined locations data
        PredefinedLocations = struct('name', {}, 'lat', {}, 'lon', {}, 'folderPath', {})

        TimelineViewerUsed = false    % Timeline Viewer 
        BlendedImageUsed = false      % Blended Image 

    end


    methods (Access = private)

        function initializeEarthGlobe(app)
            % Clear previous content
            cla(app.UIAxes);
            app.IsGlobeView = true;
            
            % Create Earth sphere with larger scale
            [x, y, z] = sphere(100); 
            scale = 2.2;
            x = x * scale;
            y = y * scale;
            z = z * scale;
            
            % Load earth texture
            texturePath = fullfile(pwd, 'Textures', 'earthmap.jpg');
            if exist(texturePath, 'file')
                try
                    earthTexture = imread(texturePath);
                    app.EarthSphere = surf(app.UIAxes, x, y, z, ...
                        'FaceColor', 'texturemap', ...
                        'EdgeColor', 'none', ...
                        'CData', flip(earthTexture, 1), ...
                        'FaceAlpha', 1, ...
                        'HitTest', 'off', ...
                        'AmbientStrength', 0.1, ...     % Low ambient for dark night side
                        'DiffuseStrength', 0.9, ...     % High diffuse for bright day side
                        'SpecularStrength', 0.3, ...    % Some specular for ocean reflection
                        'SpecularExponent', 25);        % Sharp specular highlights
                catch
                    warning('Error loading earth texture. Using fallback coloring.');
                    applyFallbackColor();
                end
            else
                warning('Texture not found at: %s. Using fallback coloring.', texturePath);
                applyFallbackColor();
            end
            
            % Setup view
            axis(app.UIAxes, 'equal');
            axis(app.UIAxes, 'off');
            view(app.UIAxes, 140, 30);


            % IMPORTANT: Set lighting mode and material
            lighting(app.UIAxes, 'phong');      % Phong for sharper shadows
            material(app.UIAxes, 'shiny');      % Shiny material for better contrast
            % material(app.UIAxes, [0.3 0.9 0.1 20 0.5])
            
            % Set up location markers
            setupPredefinedLocations(app);
            
            % Use holographic markers (they show up better in dark)
            addHolographic3DMarkers(app);
            % addRadarMarkers(app);
            % addSimpleHolographicMarkers(app);
            % addSimpleAnimatedMarkers(app);
            % addElegantMarkers(app);
            
            % Enable rotation
            enableManualRotation(app);
            startEarthRotation(app);
            
            % Title with white color for dark background
            title(app.UIAxes, 'Click a Location to Load Images (Drag to Rotate)', ...
                'FontSize', 16, 'FontWeight', 'bold', 'Color', 'white');
            
            setupDramaticDayNight(app)

             % ===== ADD MOON AFTER ALL EARTH SETUP =====
            hold(app.UIAxes, 'on');
            
            % Create moon sphere
            [mx, my, mz] = sphere(100);  % Same resolution as Earth
            moonScale = scale * 0.5;      % 50% of Earth size
            moonX = 5.5;                  % Position to the right with good spacing
            mx = mx * moonScale + moonX;
            my = my * moonScale;
            mz = mz * moonScale;
            
            % Load moon texture
            moonTexturePath = fullfile(pwd, 'Textures', 'moonmap.jpg');
            if exist(moonTexturePath, 'file')
                try
                    moonTexture = imread(moonTexturePath);
                    app.MoonSphere = surf(app.UIAxes, mx, my, mz, ...
                        'FaceColor', 'texturemap', ...
                        'EdgeColor', 'none', ...
                        'CData', flip(moonTexture, 1), ...
                        'FaceAlpha', 1, ...
                        'HitTest', 'off', ...  % Not clickable
                        'AmbientStrength', 0.3, ...
                        'DiffuseStrength', 0.7, ...
                        'SpecularStrength', 0.1, ...
                        'SpecularExponent', 10);
                catch
                    warning('Error loading moon texture. Using fallback.');
                    createFallbackMoon();
                end
            else
                warning('Moon texture not found. Using fallback.');
                createFallbackMoon();
            end
            
            % Add clickable moon label
            text(app.UIAxes, moonX, 0, moonScale * 1.3, ...
                'MOON', ...
                'Color', 'white', ...
                'FontSize', 14, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'center', ...
                'BackgroundColor', [0.1, 0.1, 0.2, 0.8], ...
                'EdgeColor', [0.6, 0.6, 0.8], ...
                'LineWidth', 1, ...
                'Margin', 2, ...
                'ButtonDownFcn', @(src, event)moonClicked(app), ...
                'HitTest', 'on');  % Clickable
            
            % Adjust view to see both
            xlim(app.UIAxes, [-3, 7]);
            ylim(app.UIAxes, [-3, 3]);
            zlim(app.UIAxes, [-3, 3]);
            
            hold(app.UIAxes, 'off');
        
            % Nested function for Earth fallback
            function applyFallbackColor()
                C = repmat(0.7, size(z));
                C(z > 0.5) = 0.3;
                C(z < -0.5) = 0.3;
                C(abs(z) < 0.3) = 0.5;
                app.EarthSphere = surf(app.UIAxes, x, y, z, ...
                    'CData', C, 'FaceColor', 'texturemap', ...
                    'EdgeColor', 'none', 'FaceAlpha', 1, ...
                    'HitTest', 'off', ...
                    'AmbientStrength', 0.1, ...
                    'DiffuseStrength', 0.9, ...
                    'SpecularStrength', 0.3, ...
                    'SpecularExponent', 25);
            end
            
            % Nested function for Moon fallback
            function createFallbackMoon()
                C = repmat(0.85, size(mz));
                for i = 1:6
                    craterX = rand() * size(C, 1);
                    craterY = rand() * size(C, 2);
                    craterSize = randi([3, 10]);
                    [X, Y] = meshgrid(1:size(C, 2), 1:size(C, 1));
                    dist = sqrt((X - craterX).^2 + (Y - craterY).^2);
                    C(dist < craterSize) = 0.65 + rand() * 0.1;
                end

                app.MoonSphere = surf(app.UIAxes, mx, my, mz, ...
                    'CData', C, 'FaceColor', 'texturemap', ...
                    'EdgeColor', 'none', ...
                    'FaceAlpha', 1, ...
                    'HitTest', 'off', ...  % Not clickable
                    'AmbientStrength', 0.3, ...
                    'DiffuseStrength', 0.7, ...
                    'SpecularStrength', 0.1, ...
                    'SpecularExponent', 10);
            end
                        
        end


        % Alternative lighting setup with more pronounced day/night
        function setupDramaticDayNight(app)
            % Clear existing lights
            delete(findall(app.UIAxes,'Type','light'));
            
            % Strong directional sun
            light(app.UIAxes, ...
                'Position', [-8, 4, 6], ...
                'Style', 'infinite', ...
                'Color', [1, 1, 0.95]);
            
            % Very subtle fill light from opposite side
            light(app.UIAxes, ...
                'Position', [10, -5, -2], ...
                'Style', 'infinite', ...
                'Color', [0.05, 0.05, 0.1]);

            % Lighting and material
            lighting(app.UIAxes, 'phong');  % Sharper shadows
            material(app.UIAxes, [0.6, 0.9, 0.2, 5]); % More pronounced shading
        end


        % ===== MOON CLICK HANDLER =====
        function moonClicked(app)
            % Define moon folder path
            moonFolderPath = fullfile(pwd, 'Datasets', 'Moon');
            
            % Check if folder exists
            if ~exist(moonFolderPath, 'dir')
                uialert(app.UIFigure, ...
                    sprintf('Moon folder not found: %s\nPlease create this folder and add moon images.', moonFolderPath), ...
                    'Folder Not Found');
                return;
            end
            
            % Stop rotation
            if ~isempty(app.RotationTimer) && isvalid(app.RotationTimer)
                stop(app.RotationTimer);
            end
            
            % Store moon folder path
            app.MoonFolderPath = moonFolderPath;
            
            try
                % use getLocationFromFolder 
                [imgs, timestamps] = getLocationFromFolder(app, moonFolderPath);
                
                if isempty(imgs)
                    uialert(app.UIFigure, 'No images found in Moon folder!', 'Error');
                    if ~isempty(app.RotationTimer) && isvalid(app.RotationTimer)
                        start(app.RotationTimer);
                    end
                    return;
                end
                
                % Update button
                app.SelectImageButton.Text = 'Back to Globe';
                app.SelectImageButton.BackgroundColor = [231/255, 111/255, 81/255];
                
                % Store data
                app.CurrentImages = imgs;
                app.CurrentTimestamps = timestamps;
                app.CurrentImageIndex = 1;
                app.ImagenameTextArea.Value = {moonFolderPath};
                
                % Clear previous processed data
                app.PreprocessedImages = {};
                app.PreprocessedFolderPath = '';
                app.AlignedImages = {};
                app.AlignedFolderPath = '';
                
                % Switch to moon image view
                app.IsGlobeView = false;
                app.IsMoonView = true;
                cla(app.UIAxes);
                
                % Display image
                displayCurrentImage(app);
                updateNavigationButtons(app);
                
                % IMPORTANT: Keep all processing buttons DISABLED for moon images
                app.PreprocessButton.Enable = 'off';
                app.AlignImagesButton.Enable = 'off';
                app.BlendedImageButton.Enable = 'off';
                app.AlignVisualButton.Enable = 'off';
                app.HeatmapButton.Enable = 'off';
                app.DifferenceHighlightButton.Enable = 'off';
                app.SelectAreaButton.Enable = 'off';
                
                % Show info message
                uialert(app.UIFigure, ...
                    'Moon images loaded for viewing only. Processing features are not available for lunar imagery.', ...
                    'View-Only Mode', 'Icon', 'info');

                % Clean up timers
                cleanupTimers(app);
                
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
                if ~isempty(app.RotationTimer) && isvalid(app.RotationTimer)
                    start(app.RotationTimer);
                end
            end
        end


        % Setup predefined locations
        function setupPredefinedLocations(app)
            % Clear existing locations
            app.PredefinedLocations = struct('name', {}, 'lat', {}, 'lon', {}, 'folderPath', {});
            
            % Define all locations including new ones
            locations = {
                % Original locations
                'Dubai', 25.2048, 55.2708, 'Dubai';
                'Wiesn', 48.1315, 11.5498, 'Wiesn';
                'Kuwait', 29.3759, 47.9774, 'Kuwait';
                'Frauenkirche', 48.1386, 11.5735, 'Frauenkirche';
                'Columbia Glacier', 61.2200, -146.9500, 'Columbia Glacier';
                'Brazilian Rainforest', -3.4653, -62.2159, 'Brazilian Rainforest';
                
                % New locations
                'Sydney', -33.8688, 151.2093, 'Sydney';
                'Los Angeles', 34.0522, -118.2437, 'Los Angeles';
                'Kapstadt', -33.9249, 18.4241, 'Kapstadt'; 
                'Beijing', 39.9042, 116.4074, 'Beijing';
            };
            
            % Create location structures
            for i = 1:size(locations, 1)
                app.PredefinedLocations(i) = struct(...
                    'name', locations{i, 1}, ...
                    'lat', locations{i, 2}, ...
                    'lon', locations{i, 3}, ...
                    'folderPath', fullfile(pwd, 'Datasets', locations{i, 4}));
            end
        end


        % Handle location click
        function locationClicked(app, locationIndex)
            loc = app.PredefinedLocations(locationIndex);
            
            % Check if folder exists
            if ~exist(loc.folderPath, 'dir')
                uialert(app.UIFigure, ...
                    sprintf('Folder not found: %s', loc.folderPath), ...
                    'Folder Not Found');
                return;
            end
            
            % Stop rotation
            if ~isempty(app.RotationTimer) && isvalid(app.RotationTimer)
                stop(app.RotationTimer);
            end
            
            % Load images from location
            app.SelectedFolderPath = loc.folderPath;
            
            try
                [imgs, timestamps] = getLocationFromFolder(app, loc.folderPath);
                
                if isempty(imgs)
                    uialert(app.UIFigure, 'No images found in folder!', 'Error');
                    if ~isempty(app.RotationTimer) && isvalid(app.RotationTimer)
                        start(app.RotationTimer);
                    end
                    return;
                end
        
                % Update button
                app.SelectImageButton.Text = 'Back to Globe';
                app.SelectImageButton.BackgroundColor = [231/255, 111/255, 81/255];
                
                % Store data
                app.CurrentImages = imgs;
                app.CurrentTimestamps = timestamps;
                app.CurrentImageIndex = 1;
                app.ImagenameTextArea.Value = {loc.folderPath};
                
                % Clear previous processed data
                app.PreprocessedImages = {};
                app.PreprocessedFolderPath = '';
                app.AlignedImages = {};
                app.AlignedFolderPath = '';
                
                % Switch to image view
                app.IsGlobeView = false;
                app.IsMoonView = false;
                cla(app.UIAxes);
                
                % IMPORTANT: Display image BEFORE updating navigation buttons
                displayCurrentImage(app);
                
                % IMPORTANT: Update navigation buttons AFTER displaying image
                updateNavigationButtons(app);
                
                % Enable preprocess button
                app.PreprocessButton.Enable = 'on';
                
                % Clean up rotation timer
                cleanupTimers(app);
                
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
                if ~isempty(app.RotationTimer) && isvalid(app.RotationTimer)
                    start(app.RotationTimer);
                end
            end
        end
                

        % Earth rotation functions
        function startEarthRotation(app)
            app.RotationTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.05, ...
                'TimerFcn', @(~,~) rotateEarth(app));
            start(app.RotationTimer);
        end
        
        function rotateEarth(app)
            if isvalid(app.UIAxes) && app.IsGlobeView
                [az, el] = view(app.UIAxes);
                view(app.UIAxes, az + 0.2, el);
            end
        end

        % Convert lat/lon to 3D coordinates
        function [x, y, z] = latlon2xyz(~, lat, lon, r)
            lat = deg2rad(lat);
            lon = deg2rad(lon);
            x = r * cos(lat) * cos(lon);
            y = r * cos(lat) * sin(lon);
            z = r * sin(lat);
        end


        % Helper function to get images from a specific folder
        function [imgs, timestamps] = getLocationFromFolder(~, folderPath)
            % Supported image extensions
            extensions = {'*.jpg', '*.jpeg', '*.png', '*.bmp', '*.tif', '*.tiff'};
            
            % Option 1: Use a cell array to collect files, then concatenate
            filesList = {};
            
            % Collect all image files matching supported extensions
            for i = 1:length(extensions)
                tempFiles = dir(fullfile(folderPath, extensions{i}));
                if ~isempty(tempFiles)
                    filesList{end+1} = tempFiles; %#ok<AGROW> % Suppress dynamic growth warning intentionally
                end
            end
            
            % If no files were found, return empty outputs
            if isempty(filesList)
                imgs = {};
                timestamps = datetime.empty(1, 0);
                return;
            end
            
            % Convert the cell array into a struct array
            files = vertcat(filesList{:});
            
            % Sort files by name
            [~, idx] = sort({files.name});
            files = files(idx);
            
            % Determine the timestamp format from the first filename
            [~, firstName, ~] = fileparts(files(1).name);
            
            % Initialize useTimeOnly explicitly to avoid "might be unused" warnings
            if strlength(firstName) == 15  % Format: YYYY_MM_DD_HHmm
                useTimeOnly = true;
            elseif strlength(firstName) == 7  % Format: YYYY_MM
                useTimeOnly = false;
            else
                % Filename format not recognized; use modification time as fallback
                
                warning('Unrecognized filename format. Using file modification time.');
                imgs = cell(1, numel(files));
                timestamps = datetime.empty(1, 0);
                
                for k = 1:numel(files)
                    fullPath = fullfile(files(k).folder, files(k).name);
                    imgs{k} = im2double(imread(fullPath));
                    timestamps(k) = datetime(files(k).datenum, 'ConvertFrom', 'datenum');
                end
                return;
            end
            
            % Preallocate memory for outputs
            n = numel(files);
            imgs = cell(1, n);
            timestamps = datetime.empty(1, 0);
            
            % Read images and parse timestamps
            for k = 1:n
                fullPath = fullfile(files(k).folder, files(k).name);
                imgs{k} = im2double(imread(fullPath));
                
                [~, nameOnly, ~] = fileparts(files(k).name);
                
                if useTimeOnly
                    % Extract time string from the last 4 characters (e.g., '1430')
                    timeStr = nameOnly(end-3:end);
                    dt = datetime(timeStr, 'InputFormat', 'HHmm');
                else
                    % Year and month format
                    dt = datetime(nameOnly, 'InputFormat', 'yyyy_MM');
                end
                
                timestamps(k) = dt;
            end
        end


        function enableManualRotation(app)
            % Enable mouse rotation
            set(app.UIFigure, 'WindowButtonDownFcn', @(src, event)startRotation(app));
            set(app.UIFigure, 'WindowButtonUpFcn', @(src, event)stopRotation(app));
            set(app.UIFigure, 'WindowButtonMotionFcn', @(src, event)doRotation(app));
        end
        
        function startRotation(app)
            if app.IsGlobeView
                app.IsRotating = true;
                app.LastMousePos = get(0, 'PointerLocation');
            end
        end
        
        function stopRotation(app)
            app.IsRotating = false;
            app.LastMousePos = [];
        end
        
        function doRotation(app)
            if app.IsRotating && ~isempty(app.LastMousePos)
                currentPos = get(0, 'PointerLocation');
                deltaX = currentPos(1) - app.LastMousePos(1);
                deltaY = currentPos(2) - app.LastMousePos(2);
                
                [az, el] = view(app.UIAxes);
                view(app.UIAxes, az - deltaX*0.5, el + deltaY*0.5);
                
                % No need to update lighting with new setup
                
                app.LastMousePos = currentPos;
            end
         end

         function clearMarkers(app)
            % Delete all markers but keep Earth and Moon
            if ~isempty(app.LocationMarkers)
                for i = 1:length(app.LocationMarkers)
                    if ishandle(app.LocationMarkers(i))
                        delete(app.LocationMarkers(i));
                    end
                end
            end
            app.LocationMarkers = [];
            
            if isvalid(app.UIAxes)
                allChildren = app.UIAxes.Children;
                
                for i = 1:length(allChildren)
                    if isvalid(allChildren(i)) && ...
                       allChildren(i) ~= app.EarthSphere && ...
                       (~isfield(app, 'MoonSphere') || allChildren(i) ~= app.MoonSphere)
                        delete(allChildren(i));
                    end
                end
            end
        end
    
        % Option 3: Satellite/Radar Style Markers
        function addRadarMarkers(app)
            clearMarkers(app);
            hold(app.UIAxes, 'on');
            
            % Clear old markers
            if ~isempty(app.LocationMarkers)
                for i = 1:length(app.LocationMarkers)
                    if ishandle(app.LocationMarkers(i))
                        delete(app.LocationMarkers(i));
                    end
                end
            end
            app.LocationMarkers = [];
            
            for i = 1:length(app.PredefinedLocations)
                loc = app.PredefinedLocations(i);
                [x, y, z] = latlon2xyz(app, loc.lat, loc.lon, 2.2);
                
                % Create radar ring effect
                for ring = 1:3
                    ringSize = 10 + ring * 8;
                    lineWidth = 2 - ring * 0.5;
                    color = [0, 1, 0.3] * (1 - ring * 0.2); % Fade color
                    
                    plot3(app.UIAxes, x, y, z, 'o', ...
                        'MarkerSize', ringSize, ...
                        'MarkerEdgeColor', color, ...
                        'MarkerFaceColor', 'none', ...
                        'LineWidth', lineWidth);
                end
                
                % Center dot
                h = plot3(app.UIAxes, x, y, z, 'o', ...
                    'MarkerSize', 8, ...
                    'MarkerFaceColor', [0, 1, 0.3], ...
                    'MarkerEdgeColor', 'white', ...
                    'LineWidth', 1, ...
                    'HitTest', 'on', ...
                    'ButtonDownFcn', @(src, event)locationClicked(app, i));
                
                % Scanning line effect
                scanAngle = i * 30; % Different angle for each location
                scanLength = 0.15;
                [sx, sy, sz] = latlon2xyz(app, loc.lat + scanLength * cosd(scanAngle), ...
                                           loc.lon + scanLength * sind(scanAngle), 2.2);
                plot3(app.UIAxes, [x, sx], [y, sy], [z, sz], '-', ...
                    'Color', [0, 1, 0.3], 'LineWidth', 2);
                
                % Tech-style label
                [lx, ly, lz] = latlon2xyz(app, loc.lat, loc.lon, 2.2 * 1.12);
                text(app.UIAxes, lx, ly, lz, ...
                    sprintf('[ %s ]', upper(loc.name)), ...
                    'Color', [0, 1, 0.3], ...
                    'FontSize', 8, ...
                    'FontName', 'FixedWidth', ... % Use generic fixed-width font
                    'FontWeight', 'bold', ...
                    'HorizontalAlignment', 'center', ...
                    'BackgroundColor', [0, 0.1, 0.05], ...
                    'EdgeColor', [0, 1, 0.3], ...
                    'LineWidth', 1, ...
                    'Margin', 2, ...
                    'HitTest', 'on', ...
                    'ButtonDownFcn', @(src, event)locationClicked(app, i));
                
                app.LocationMarkers(end+1) = h;
            end
            
            hold(app.UIAxes, 'off');
        end
        
        % Simpler animated markers that work with MATLAB limitations
        function addSimpleAnimatedMarkers(app)
            clearMarkers(app);
            hold(app.UIAxes, 'on');
            
            % Clear old markers
            if ~isempty(app.LocationMarkers)
                for i = 1:length(app.LocationMarkers)
                    if ishandle(app.LocationMarkers(i))
                        delete(app.LocationMarkers(i));
                    end
                end
            end
            app.LocationMarkers = [];
            
            % Color scheme for different regions
            colors = {
                [1, 0.5, 0];      % Orange
                [0.8, 0, 0];      % Red  
                [0, 0.6, 1];      % Sky Blue
                [1, 0.8, 0];      % Gold
                [0.2, 0.8, 0.2];  % Green
                [0.8, 0.2, 0.8];  % Purple
            };
            
            for i = 1:length(app.PredefinedLocations)
                loc = app.PredefinedLocations(i);
                [x, y, z] = latlon2xyz(app, loc.lat, loc.lon, 2.2);
                
                % Select color
                colorIdx = mod(i-1, length(colors)) + 1;
                markerColor = colors{colorIdx};
                
                % Create multi-layer effect using multiple markers
                % Outer ring
                plot3(app.UIAxes, x, y, z, 'o', ...
                    'MarkerSize', 20, ...
                    'MarkerEdgeColor', markerColor, ...
                    'MarkerFaceColor', 'none', ...
                    'LineWidth', 1);
                
                % Middle dot
                plot3(app.UIAxes, x, y, z, 'o', ...
                    'MarkerSize', 15, ...
                    'MarkerFaceColor', markerColor * 0.7, ...
                    'MarkerEdgeColor', 'none');
                
                % Inner core (clickable)
                h = plot3(app.UIAxes, x, y, z, 'o', ...
                    'MarkerSize', 10, ...
                    'MarkerFaceColor', markerColor, ...
                    'MarkerEdgeColor', 'white', ...
                    'LineWidth', 2, ...
                    'HitTest', 'on', ...
                    'ButtonDownFcn', @(src, event)locationClicked(app, i));
                
                % Vertical beam
                beamHeight = 0.2;
                [bx, by, bz] = latlon2xyz(app, loc.lat, loc.lon, 2.2 + beamHeight);
                plot3(app.UIAxes, [x, bx], [y, by], [z, bz], '-', ...
                    'Color', markerColor, 'LineWidth', 3);
                
                % Label
                labelDist = 1.15;
                [lx, ly, lz] = latlon2xyz(app, loc.lat, loc.lon, 2.2 * labelDist);
                
                text(app.UIAxes, lx, ly, lz, loc.name, ...
                    'Color', 'white', ...
                    'FontSize', 10, ...
                    'FontWeight', 'bold', ...
                    'HorizontalAlignment', 'center', ...
                    'BackgroundColor', markerColor * 0.8, ...
                    'EdgeColor', markerColor, ...
                    'LineWidth', 2, ...
                    'Margin', 3, ...
                    'HitTest', 'on', ...
                    'ButtonDownFcn', @(src, event)locationClicked(app, i));
                
                app.LocationMarkers(end+1) = h;
            end
            
            hold(app.UIAxes, 'off');
        end
        
        % Most compatible version - simple but elegant
        function addElegantMarkers(app)
            clearMarkers(app);
            hold(app.UIAxes, 'on');
            
            % Clear old markers
            if ~isempty(app.LocationMarkers)
                for i = 1:length(app.LocationMarkers)
                    if ishandle(app.LocationMarkers(i))
                        delete(app.LocationMarkers(i));
                    end
                end
            end
            app.LocationMarkers = [];
            
            for i = 1:length(app.PredefinedLocations)
                loc = app.PredefinedLocations(i);
                [x, y, z] = latlon2xyz(app, loc.lat, loc.lon, 2.2);
                
                % Create shadow effect
                shadowOffset = 0.02;
                [sx, sy, sz] = latlon2xyz(app, loc.lat - shadowOffset, ...
                                           loc.lon - shadowOffset, 2.19);
                plot3(app.UIAxes, sx, sy, sz, 'o', ...
                    'MarkerSize', 12, ...
                    'MarkerFaceColor', [0.2, 0.2, 0.2], ...
                    'MarkerEdgeColor', 'none');
                
                % Main marker with gradient effect
                h = plot3(app.UIAxes, x, y, z, 'o', ...
                    'MarkerSize', 12, ...
                    'MarkerFaceColor', [0.8, 0.2, 0.2], ...
                    'MarkerEdgeColor', 'white', ...
                    'LineWidth', 2, ...
                    'HitTest', 'on', ...
                    'ButtonDownFcn', @(src, event)locationClicked(app, i));
                
                % Connecting line to label
                labelDist = 1.15;
                [lx, ly, lz] = latlon2xyz(app, loc.lat, loc.lon, 2.2 * labelDist);
                plot3(app.UIAxes, [x, lx], [y, ly], [z, lz], '-', ...
                    'Color', [0.5, 0.5, 0.5], 'LineWidth', 1);
                
                % Elegant label
                text(app.UIAxes, lx, ly, lz, loc.name, ...
                    'Color', 'black', ...
                    'FontSize', 10, ...
                    'FontWeight', 'bold', ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle', ...
                    'BackgroundColor', [1, 1, 1, 0.9], ...
                    'EdgeColor', [0.8, 0.2, 0.2], ...
                    'LineWidth', 1.5, ...
                    'Margin', 3, ...
                    'HitTest', 'on', ...
                    'ButtonDownFcn', @(src, event)locationClicked(app, i));
                
                app.LocationMarkers(end+1) = h;
            end
            
            hold(app.UIAxes, 'off');
        end
      
        % Add Holographic 3D Rotating Markers
        function addHolographic3DMarkers(app)
            clearMarkers(app);
            hold(app.UIAxes, 'on');
            
            % Clear old markers
            if ~isempty(app.LocationMarkers)
                for i = 1:length(app.LocationMarkers)
                    if ishandle(app.LocationMarkers(i))
                        delete(app.LocationMarkers(i));
                    end
                end
            end
            app.LocationMarkers = [];
            
            % Gentle holographic color palette - soft and warm
            holoColors = {
                [0.7, 0.9, 1];      % Soft sky blue
                [1, 0.8, 0.9];      % Gentle pink
                [0.8, 1, 0.8];      % Soft mint green
                [1, 0.9, 0.7];      % Warm peach
                [0.9, 0.8, 1];      % Lavender
                [0.8, 0.95, 0.9];   % Soft aqua
                [1, 0.85, 0.8];     % Coral pink
                [0.85, 0.9, 1];     % Powder blue
            };
            
            for i = 1:length(app.PredefinedLocations)
                loc = app.PredefinedLocations(i);
                [x, y, z] = latlon2xyz(app, loc.lat, loc.lon, 2.2);
                
                % Select gentle holographic color
                colorIdx = mod(i-1, length(holoColors)) + 1;
                holoColor = holoColors{colorIdx};
                
                % Create 3D holographic pyramid/diamond shape
                pyramidHeight = 0.04;
                pyramidWidth = 0.02;
                
                % Define pyramid vertices
                [tx, ty, tz] = latlon2xyz(app, loc.lat, loc.lon, 2.2 + pyramidHeight);
                [bx, by, bz] = latlon2xyz(app, loc.lat, loc.lon, 2.2 - pyramidHeight/2);
                
                % Middle ring vertices
                angles = [0, 90, 180, 270];
                ringX = zeros(1, 4);
                ringY = zeros(1, 4);
                ringZ = zeros(1, 4);
                
                for j = 1:4
                    [ringX(j), ringY(j), ringZ(j)] = latlon2xyz(app, ...
                        loc.lat + pyramidWidth * cosd(angles(j)), ...
                        loc.lon + pyramidWidth * sind(angles(j)), 2.2);
                end
                
                % Draw holographic wireframe with softer intensity
                for j = 1:4
                    nextJ = mod(j, 4) + 1;
                    % Top to ring - softer lines
                    plot3(app.UIAxes, [tx, ringX(j)], [ty, ringY(j)], [tz, ringZ(j)], ...
                        'Color', holoColor * 0.8, 'LineWidth', 1.2);
                    % Ring edges - more subtle
                    plot3(app.UIAxes, [ringX(j), ringX(nextJ)], ...
                        [ringY(j), ringY(nextJ)], [ringZ(j), ringZ(nextJ)], ...
                        'Color', holoColor * 0.6, 'LineWidth', 1);
                    % Ring to bottom - very gentle
                    plot3(app.UIAxes, [ringX(j), bx], [ringY(j), by], [ringZ(j), bz], ...
                        'Color', holoColor * 0.4, 'LineWidth', 0.8);
                end
                
                % Gentle glow effects
                % Outer glow - very soft
                plot3(app.UIAxes, x, y, z, 'o', ...
                    'MarkerSize', 12, ...
                    'MarkerFaceColor', holoColor * 0.2, ...
                    'MarkerEdgeColor', 'none');
                
                % Middle glow - soft
                plot3(app.UIAxes, x, y, z, 'o', ...
                    'MarkerSize', 8, ...
                    'MarkerFaceColor', holoColor * 0.4, ...
                    'MarkerEdgeColor', 'none');
                
                % Inner core (clickable) - gentle brightness
                h = plot3(app.UIAxes, x, y, z, 'o', ...
                    'MarkerSize', 4, ...
                    'MarkerFaceColor', holoColor * 0.8, ...
                    'MarkerEdgeColor', [0.9, 0.9, 0.9], ...
                    'LineWidth', 1, ...
                    'HitTest', 'on', ...
                    'ButtonDownFcn', @(src, event)locationClicked(app, i));
                
                % Gentle energy beam effect
                beamHeight = 0.5;
                [beamX, beamY, beamZ] = latlon2xyz(app, loc.lat, loc.lon, 2.2 + beamHeight);
                
                % Create beam with soft gradient effect
                numBeamLines = 3;
                for b = 1:numBeamLines
                    beamAlpha = 0.9 - (b-1) / numBeamLines * 0.4; % Softer maximum intensity
                    beamWidth = 1.5 * beamAlpha;
                    plot3(app.UIAxes, [x, beamX], [y, beamY], [z, beamZ], '-', ...
                        'Color', holoColor * beamAlpha, 'LineWidth', beamWidth);
                end
                
                % Floating holographic label with gentle styling
                labelDist = 1.3;
                [lx, ly, lz] = latlon2xyz(app, loc.lat, loc.lon, 2.2 * labelDist);
                
                % Create gentle holographic text
                text(app.UIAxes, lx, ly, lz, upper(loc.name), ...
                    'Color', holoColor * 0.9, ...
                    'FontSize', 10, ...
                    'FontWeight', 'normal', ...
                    'FontAngle', 'normal', ...
                    'HorizontalAlignment', 'center', ...
                    'BackgroundColor', [0.05, 0.05, 0.1], ...
                    'EdgeColor', holoColor * 0.6, ...
                    'LineWidth', 1, ...
                    'Margin', 3, ...
                    'HitTest', 'on', ...
                    'ButtonDownFcn', @(src, event)locationClicked(app, i));
                
                % Gentle data stream effect
                numStreams = 1;
                for s = 1:numStreams
                    streamOffset = (s - 1.5) * 0.01;
                    [sx, sy, sz] = latlon2xyz(app, loc.lat + streamOffset, ...
                        loc.lon + streamOffset, 2.2);
                    [sx2, sy2, sz2] = latlon2xyz(app, loc.lat + streamOffset, ...
                        loc.lon + streamOffset, 2.2 + 0.05);
                    plot3(app.UIAxes, [sx, sx2], [sy, sy2], [sz, sz2], ':', ...
                        'Color', holoColor * 0.3, 'LineWidth', 0.8);
                end
                
                app.LocationMarkers(end+1) = h;
            end
            
            
            hold(app.UIAxes, 'off');
        end
        
        % % Add holographic grid overlay
        % function addHolographicGrid(app)
        %     % Grid color
        %     gridColor = [0, 1, 1] * 0.2; % Dim cyan
        % 
        %     % Create latitude lines
        %     lats = -60:30:60;
        %     for lat = lats
        %         lon = linspace(-180, 180, 100);
        %         x = 2.21 * cosd(lat) * cosd(lon);
        %         y = 2.21 * cosd(lat) * sind(lon);
        %         z = 2.21 * sind(lat) * ones(size(lon));
        %         plot3(app.UIAxes, x, y, z, '-', ...
        %             'Color', gridColor, 'LineWidth', 0.5);
        %     end
        % 
        %     % Create longitude lines
        %     lons = -180:45:180;
        %     for lon = lons
        %         lat = linspace(-80, 80, 50);
        %         x = 2.21 * cosd(lat) * cosd(lon);
        %         y = 2.21 * cosd(lat) * sind(lon);
        %         z = 2.21 * sind(lat);
        %         plot3(app.UIAxes, x, y, z, '-', ...
        %             'Color', gridColor, 'LineWidth', 0.5);
        %     end
        % end
    
    
        % Simplified animated version without transparency issues
        function addSimpleHolographicMarkers(app)
            clearMarkers(app);
            hold(app.UIAxes, 'on');
            
            if ~isempty(app.LocationMarkers)
                for i = 1:length(app.LocationMarkers)
                    if ishandle(app.LocationMarkers(i))
                        delete(app.LocationMarkers(i));
                    end
                end
            end
            app.LocationMarkers = [];
            
            for i = 1:length(app.PredefinedLocations)
                loc = app.PredefinedLocations(i);
                [x, y, z] = latlon2xyz(app, loc.lat, loc.lon, 2.2);
                
                % Holographic cyan color
                holoColor = [0, 1, 1];
                
                % Create rotating rings
                numPoints = 20;
                theta = linspace(0, 2*pi, numPoints);
                ringRadius = 0.03;
                
                % Horizontal ring
                ringX = x + ringRadius * cos(theta);
                ringY = y + ringRadius * sin(theta);
                ringZ = repmat(z, 1, numPoints);
                
                plot3(app.UIAxes, ringX([1:end 1]), ringY([1:end 1]), ringZ([1:end 1]), '-', ...
                    'Color', holoColor, 'LineWidth', 2);
                
                % Vertical ring
                phi = linspace(0, 2*pi, numPoints);
                ringX2 = x + ringRadius * cos(phi) * 0.7;
                ringZ2 = z + ringRadius * sin(phi);
                ringY2 = repmat(y, 1, numPoints);
                
                plot3(app.UIAxes, ringX2([1:end 1]), ringY2([1:end 1]), ringZ2([1:end 1]), '-', ...
                    'Color', [1, 0, 1], 'LineWidth', 2);
                
                % Center core
                h = plot3(app.UIAxes, x, y, z, 'o', ...
                    'MarkerSize', 12, ...
                    'MarkerFaceColor', holoColor, ...
                    'MarkerEdgeColor', 'white', ...
                    'LineWidth', 2, ...
                    'HitTest', 'on', ...
                    'ButtonDownFcn', @(src, event)locationClicked(app, i));
                
                % Energy pillar
                pillarHeight = 0.2;
                [px, py, pz] = latlon2xyz(app, loc.lat, loc.lon, 2.2 + pillarHeight);
                plot3(app.UIAxes, [x, px], [y, py], [z, pz], '-', ...
                    'Color', holoColor, 'LineWidth', 3);
                
                % Label
                [lx, ly, lz] = latlon2xyz(app, loc.lat, loc.lon, 2.2 * 1.2);
                text(app.UIAxes, lx, ly, lz, upper(loc.name), ...
                    'Color', holoColor, ...
                    'FontSize', 10, ...
                    'FontWeight', 'bold', ...
                    'HorizontalAlignment', 'center', ...
                    'BackgroundColor', 'black', ...
                    'EdgeColor', holoColor, ...
                    'LineWidth', 1, ...
                    'Margin', 3, ...
                    'HitTest', 'on', ...
                    'ButtonDownFcn', @(src, event)locationClicked(app, i));
                
                app.LocationMarkers(end+1) = h;
            end
            
            hold(app.UIAxes, 'off');
        end


        %%Earth
        %%-------------------------------------------------------------------------------------------------------------------
        
        % Display current image
        function displayCurrentImage(app)
            if ~isempty(app.CurrentImages) && app.CurrentImageIndex > 0
                % Clear and display
                cla(app.UIAxes);
                imagesc(app.UIAxes, app.CurrentImages{app.CurrentImageIndex});
                
                % Setup 2D view
                axis(app.UIAxes, 'image');
                colormap(app.UIAxes, 'gray');
                view(app.UIAxes, 2);
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.Box = 'on';
                
                % Update title
                totalImages = length(app.CurrentImages);
                currentTimestamp = char(app.CurrentTimestamps(app.CurrentImageIndex));

                if app.IsMoonView
                    % Special title for moon images
                    app.UIAxes.Title.String = sprintf('Moon Image %d of %d - Date: %s (View Only)', ...
                        app.CurrentImageIndex, totalImages, currentTimestamp);
                    app.UIAxes.Title.FontWeight = 'bold';
                    app.UIAxes.Title.Color = [0.7, 0.7, 1];  % Light blue color for moon
                else
                    % Regular title for Earth images
                    app.UIAxes.Title.String = sprintf('Image %d of %d - Date: %s', ...
                        app.CurrentImageIndex, totalImages, currentTimestamp);
                    app.UIAxes.Title.FontWeight = 'bold';
                    app.UIAxes.Title.Color = [1, 1, 1];  % White color for Earth
                end
            end
        end


        % Update navigation button states
        function updateNavigationButtons(app)
            fprintf('Images: %d, Current: %d\n', length(app.CurrentImages), app.CurrentImageIndex);
            if isempty(app.CurrentImages)
                app.PreviousButton.Enable = 'off';
                app.NextButton.Enable = 'off';
            else
                % Debug: Print current state
                fprintf('UpdateNavButtons: CurrentIndex=%d, TotalImages=%d\n', ...
                    app.CurrentImageIndex, length(app.CurrentImages));
                
                % Previous button
                if app.CurrentImageIndex <= 1
                    app.PreviousButton.Enable = 'off';
                else
                    app.PreviousButton.Enable = 'on';
                end
                
                % Next button  
                if app.CurrentImageIndex >= length(app.CurrentImages)
                    app.NextButton.Enable = 'off';
                else
                    app.NextButton.Enable = 'on';
                end
            end
        end


        % Clean old cache folders automatically
        function cleanOldCache(~, keepCount)
            cacheDir = fullfile(pwd, '.cache');
            if exist(cacheDir, 'dir')
                % Get all aligned folders
                alignedFolders = dir(fullfile(cacheDir, 'aligned_*'));
                
                % Keep only the most recent folders
                if length(alignedFolders) > keepCount
                    % Sort by date
                    [~, idx] = sort([alignedFolders.datenum], 'descend');
                    alignedFolders = alignedFolders(idx);
                    
                    % Delete old folders
                    for i = (keepCount+1):length(alignedFolders)
                        try
                            rmdir(fullfile(cacheDir, alignedFolders(i).name), 's');
                        catch
                            % Ignore errors if folder is in use
                        end
                    end
                end
            end
        end

        % Play next image for slider
        function playNextImage(app)
            if isempty(app.CurrentImages)
                stopPlayback(app);
                return;
            end
            
            if app.CurrentImageIndex < length(app.CurrentImages)
                app.CurrentImageIndex = app.CurrentImageIndex + 1;
            else
                app.CurrentImageIndex = 1;
            end
            
            displayCurrentImage(app);
            updateNavigationButtons(app);
        end

        % Stop playback
        function stopPlayback(app)
            if ~isempty(app.PlaybackTimer) && isvalid(app.PlaybackTimer)
                stop(app.PlaybackTimer);
                delete(app.PlaybackTimer);
            end
            app.PlaybackSpeed = 0;
            app.TimeSlider.Value = 0;
        end


        % Centralized timer cleanup
        function cleanupTimers(app)
            timers = {app.PlaybackTimer, app.RotationTimer};
            for i = 1:length(timers)
                if ~isempty(timers{i}) && isvalid(timers{i})
                    stop(timers{i});
                    delete(timers{i});
                end
            end
            app.PlaybackTimer = [];
            app.RotationTimer = [];
        end

        function checkAndEnableAdvancedButtons(app)
            if app.TimelineViewerUsed && app.BlendedImageUsed
                app.HeatmapButton.Enable = 'on';
                app.DifferenceHighlightButton.Enable = 'on';
                app.SelectAreaButton.Enable = 'on';
                
                uialert(app.UIFigure, ...
                    'AlignVisual and Blended Image completed! Heatmap, Difference Highlight and Select Area Mode are now available!', ...
                    'Features Unlocked', 'Icon', 'success');
            end
        end

        function startRectSelect(app)
            if app.ZoomEnabled
                % Get current mouse click position
                cp = get(app.UIAxes, 'CurrentPoint');
                xlims = xlim(app.UIAxes);
                ylims = ylim(app.UIAxes);
        
                % Check if click is inside the visible axis area
                if cp(1,1) >= xlims(1) && cp(1,1) <= xlims(2) && ...
                   cp(1,2) >= ylims(1) && cp(1,2) <= ylims(2)
        
                    % Draw rectangle for zoom selection
                    rbbox;
        
                    % Get second corner of the box
                    cp2 = get(app.UIAxes, 'CurrentPoint');
        
                    % Determine new zoom limits
                    newXLim = sort([cp(1,1), cp2(1,1)]);
                    newYLim = sort([cp(1,2), cp2(1,2)]);
        
                    % Clamp to axis bounds
                    newXLim(1) = max(newXLim(1), xlims(1));
                    newXLim(2) = min(newXLim(2), xlims(2));
                    newYLim(1) = max(newYLim(1), ylims(1));
                    newYLim(2) = min(newYLim(2), ylims(2));
        
                    % Apply new limits if the selected area is large enough
                    if diff(newXLim) > 10 && diff(newYLim) > 10
                        xlim(app.UIAxes, newXLim);
                        ylim(app.UIAxes, newYLim);
                    end
                end
            end
        end


        function centerWindow(~, fig, width, height)
            screenSize = get(0, 'ScreenSize');  % [x y width height]
            x = (screenSize(3) - width) / 2;
            y = (screenSize(4) - height) / 2;
            fig.Position = [x, y, width, height];
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize properties
            app.LocationMarkers = [];
            app.CurrentImages = {};
            app.CurrentTimestamps = [];
            app.CurrentImageIndex = 0;
            app.SelectedFolderPath = '';
            app.AlignedImages = {};
            app.AlignedFolderPath = '';
            app.Tforms = {};
            app.AlignStatuses = [];
            app.PreprocessedImages = {};
            app.PreprocessedFolderPath = '';
            
            % Initialize features
            app.ZoomEnabled = false;
            app.PlaybackTimer = [];
            app.PlaybackSpeed = 0;
            app.IsGlobeView = true;
            
            % Configure TimeSlider
            app.TimeSlider.Limits = [0 100];
            app.TimeSlider.Value = 0;
            app.TimeSlider.MajorTicks = [0 20 40 60 80 100];
            app.TimeSlider.MajorTickLabels = {'Stop', 'Slow', '', 'Medium', '', 'Fast'};
            
            % Disable all buttons initially
            app.PreviousButton.Enable = 'off';
            app.NextButton.Enable = 'off';
            app.PreprocessButton.Enable = 'off';
            app.AlignImagesButton.Enable = 'off';
            app.BlendedImageButton.Enable = 'off';
            app.AlignVisualButton.Enable = 'off';
            app.HeatmapButton.Enable = 'off';
            app.DifferenceHighlightButton.Enable = 'off';
            app.SelectAreaButton.Enable = 'off';
            app.TimelineViewerUsed = false;
            app.BlendedImageUsed = false;
            
            % Initialize Earth globe
            initializeEarthGlobe(app);
            
            % Clean old cache
            app.cleanOldCache(5);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            stopPlayback(app);
            
            if app.IsGlobeView
                % Select custom folder
                try
                    [imgs, timestamps, folderPath] = getLocation();
                    
                    app.CurrentImages = imgs;
                    app.CurrentTimestamps = timestamps;
                    app.SelectedFolderPath = folderPath;
                    app.CurrentImageIndex = 1;
                    
                    app.ImagenameTextArea.Value = {app.SelectedFolderPath};
                    
                    % Clear previous data
                    app.PreprocessedImages = {};
                    app.PreprocessedFolderPath = '';
                    app.AlignedImages = {};
                    app.AlignedFolderPath = '';
                    
                    % Switch to image view
                    app.IsGlobeView = false;
                    app.IsMoonView = false;  % Not moon view
                    cla(app.UIAxes);
                    displayCurrentImage(app);
                    updateNavigationButtons(app);
                    
                    % Enable preprocess button for custom folders
                    app.PreprocessButton.Enable = 'on';
                    app.SelectImageButton.Text = 'Back to Globe';
                    app.SelectImageButton.BackgroundColor = [231/255, 111/255, 81/255];
                    
                    cleanupTimers(app);
                    
                catch ME
                    if ~strcmp(ME.message, 'Folder selection canceled by user.')
                        uialert(app.UIFigure, ME.message, 'Error');
                    end
                end
            else
                % Return to globe
                app.IsGlobeView = true;
                app.IsMoonView = false;
                app.SelectImageButton.Text = 'Select Image';
                app.SelectImageButton.BackgroundColor = [231/255, 111/255, 81/255];
                initializeEarthGlobe(app);
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.CurrentImageIndex > 1
                app.CurrentImageIndex = app.CurrentImageIndex - 1;
                displayCurrentImage(app);
                updateNavigationButtons(app);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.CurrentImageIndex < length(app.CurrentImages)
                app.CurrentImageIndex = app.CurrentImageIndex + 1;
                displayCurrentImage(app);
                updateNavigationButtons(app);
            end
        end

        % Button pushed function: AlignImagesButton
        function AlignImagesButtonPushed(app, event)
            % Check if preprocess is done
            if isempty(app.PreprocessedFolderPath)
                uialert(app.UIFigure, 'Please preprocess images first!', 'Error');
                return;
            end
            
            try
                % Call the alignPairAuto function with preprocessed folder path
                [alignedImgs, tforms, statuses, outputPath] = alignPairAuto(app.PreprocessedFolderPath);
                
                % Store aligned results
                app.AlignedImages = alignedImgs;  % Now includes reference image
                app.Tforms = tforms;
                app.AlignStatuses = statuses;
                app.AlignedFolderPath = outputPath;
                
                % Show success message
                successCount = sum(statuses == "success");
                msg = sprintf('Alignment completed!\n%d/%d images successfully aligned.\nSaved to: %s', ...
                    successCount, length(statuses), outputPath);
                uialert(app.UIFigure, msg, 'Success', 'Icon', 'success');
                
                % Enable ALL visualization buttons

                app.TimelineViewerUsed = false;
                app.BlendedImageUsed = false;
                
        
                app.AlignVisualButton.Enable = 'on';
                app.BlendedImageButton.Enable = 'on';
                app.SelectAreaButton.Enable = 'on';
                
        
                app.HeatmapButton.Enable = 'off';
                app.DifferenceHighlightButton.Enable = 'off';
                app.SelectAreaButton.Enable = 'off';
                
                % Ask if user wants to switch to aligned images
                answer = uiconfirm(app.UIFigure, 'Do you want to view the aligned images?', ...
                    'View Aligned Images', 'Options', {'Yes', 'No'}, 'DefaultOption', 1);
                
                if strcmp(answer, 'Yes')
                    % Switch to aligned images
                    app.CurrentImages = app.AlignedImages;
                    app.CurrentImageIndex = 1;
                    displayCurrentImage(app);
                    updateNavigationButtons(app);
                end
                
            catch ME
                uialert(app.UIFigure, ME.message, 'Alignment Error');
            end
        end

        % Button pushed function: ClearCacheButton
        function ClearCacheButtonPushed(app, event)
            cacheDir = fullfile(pwd, '.cache');
            if ~exist(cacheDir, 'dir')
                uialert(app.UIFigure, 'No cache folder found.', 'Info');
                return;
            end
        
            % Confirm with user
            answer = uiconfirm(app.UIFigure, ...
                'This will delete all cached aligned, preprocessed folders. Continue?', ...
                'Clear Cache', ...
                'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);
        
            if strcmp(answer, 'Yes')
                try
                    % Get all folders in .cache
                    folderList = dir(cacheDir);
                    deletedCount = 0;
        
                    for i = 1:length(folderList)
                        folderName = folderList(i).name;
                        folderPath = fullfile(cacheDir, folderName);
        
                        % Skip non-folders or . and ..
                        if ~folderList(i).isdir || strcmp(folderName, '.') || strcmp(folderName, '..')
                            continue;
                        end
        
                        % Skip currently loaded folders
                        if strcmp(folderPath, app.AlignedFolderPath) || strcmp(folderPath, app.PreprocessedFolderPath)
                            continue;
                        end
        
                        % Delete folders that match:
                        % - aligned_*
                        % - preprocessed_*
                        % - <any name>_YYYYMMDD_HHMMSS (with or without spaces)
                        if startsWith(folderName, 'aligned_') || ...
                           startsWith(folderName, 'preprocessed_') || ...
                           ~isempty(regexp(folderName, '^[\w\s]+_\d{8}_\d{6}$', 'once'))
                            rmdir(folderPath, 's');
                            deletedCount = deletedCount + 1;
                        end

                    end
        
                    msg = sprintf('Successfully deleted %d cached folder(s).', deletedCount);
                    uialert(app.UIFigure, msg, 'Success', 'Icon', 'success');
        
                catch ME
                    uialert(app.UIFigure, ['Error clearing cache: ' ME.message], 'Error');
                end
            end
        end

        % Button pushed function: BlendedImageButton
        function BlendedImageButtonPushed(app, event)
            if isempty(app.AlignedFolderPath)
                uialert(app.UIFigure, 'Please align images first!', 'Error');
                return;
            end
            
            % Check if aligned folder exists
            if ~exist(app.AlignedFolderPath, 'dir')
                uialert(app.UIFigure, 'Aligned folder not found. Please align images again.', 'Error');
                return;
            end
            
            try
                % Call BlendedImgGUI with aligned folder path
                BlendedImgGUI(app.AlignedFolderPath);
                app.BlendedImageUsed = true;
               
                checkAndEnableAdvancedButtons(app);
            catch ME
                uialert(app.UIFigure, ['Error opening Blended Image viewer: ' ME.message], 'Error');
            end
        end

        % Button pushed function: AlignVisualButton
        function AlignVisualButtonPushed(app, event)
            if isempty(app.AlignedFolderPath)
                uialert(app.UIFigure, 'Please align images first!', 'Error');
                return;
            end
            
            % Check if aligned folder exists
            if ~exist(app.AlignedFolderPath, 'dir')
                uialert(app.UIFigure, 'Aligned folder not found. Please align images again.', 'Error');
                return;
            end
            
            try
                % Call alignmentVisualizer with aligned folder and transformation data
                alignmentVisualizer(app.PreprocessedFolderPath, app.Tforms);
                app.TimelineViewerUsed = true;
                
                % check active state
                checkAndEnableAdvancedButtons(app);
            catch ME
                uialert(app.UIFigure, ['Error opening AlignVisual: ' ME.message], 'Error');
            end
        end

        % Button pushed function: PreprocessButton
        function PreprocessButtonPushed(app, event)
            if isempty(app.CurrentImages)
                uialert(app.UIFigure, 'Please select images first!', 'Error');
                return;
            end
            
            modes = {'auto', 'basic', 'retinex', 'brightness-only', 'skip'};
            
            modeDescriptions = {
                'auto - Automatic enhancement', ...
                'basic - Basic preprocessing', ...
                'retinex - Retinex enhancement', ...
                'brightness-only - Only adjust brightness', ...
                'skip - Only filtering and clipping'
            };
            
            [selectedIndex, tf] = listdlg('ListString', modeDescriptions, ...
                'SelectionMode', 'single', ...
                'Name', 'Select Preprocessing Mode', ...
                'PromptString', 'Choose preprocessing mode:', ...
                'ListSize', [300, 150]);
            
            if ~tf
                return; % User cancelled
            end
            
            % Get the selected mode
            mode = modes{selectedIndex};
            
            try
                % Show progress dialog
                d = uiprogressdlg(app.UIFigure, 'Title', 'Preprocessing Images', ...
                    'Message', sprintf('Processing with mode: %s...', mode), ...
                    'Indeterminate', 'on');
                
                % Call prepImages function with selected mode
                app.PreprocessedImages = prepImages(app.CurrentImages, mode);
                
                % Create folder in .cache for preprocessed images
                timestamp = string(datetime('now', 'Format', 'yyyyMMdd_HHmmss'));
                cacheDir = fullfile(pwd, '.cache');
                if ~exist(cacheDir, 'dir')
                    mkdir(cacheDir);
                end
                app.PreprocessedFolderPath = fullfile(cacheDir, ['preprocessed_' char(timestamp)]);
                mkdir(app.PreprocessedFolderPath);
                
                % Get original filenames
                originalFiles = dir(fullfile(app.SelectedFolderPath, '*.jpg'));
                if isempty(originalFiles)
                    % Try other extensions if jpg not found
                    extensions = {'*.jpeg', '*.png', '*.bmp', '*.tif', '*.tiff'};
                    for ext = extensions
                        originalFiles = dir(fullfile(app.SelectedFolderPath, ext{1}));
                        if ~isempty(originalFiles)
                            break;
                        end
                    end
                end
                
                % Save preprocessed images
                for i = 1:length(app.PreprocessedImages)
                    if i <= length(originalFiles)
                        filename = originalFiles(i).name;
                    else
                        filename = sprintf('image_%03d.jpg', i);
                    end
                    imwrite(app.PreprocessedImages{i}, fullfile(app.PreprocessedFolderPath, filename));
                end
                
                % Close progress dialog
                close(d);
                
                % Show success message
                if strcmp(mode, 'skip')
                    msg = sprintf('Images copied without preprocessing.\nSaved to: %s', ...
                        app.PreprocessedFolderPath);
                else
                    msg = sprintf('Preprocessing completed!\nMode: %s\nSaved to: %s', ...
                        mode, app.PreprocessedFolderPath);
                end
                uialert(app.UIFigure, msg, 'Success', 'Icon', 'success');
                
                % Enable align button
                app.AlignImagesButton.Enable = 'on';
                
                % Automatically switch to and display preprocessed images                
                app.CurrentImages = app.PreprocessedImages;
                app.CurrentImageIndex = 1;
                displayCurrentImage(app);
                updateNavigationButtons(app);
            
            catch ME
                if exist('d', 'var')
                    close(d);
                end
                uialert(app.UIFigure, ['Error during preprocessing: ' ME.message], 'Error');
            end
        end

        % Button pushed function: DifferenceHighlightButton
        function DifferenceHighlightButtonPushed(app, event)
            if isempty(app.AlignedFolderPath)
                uialert(app.UIFigure, 'Please align images first!', 'Error');
                return;
            end
            
            % Check if aligned folder exists
            if ~exist(app.AlignedFolderPath, 'dir')
                uialert(app.UIFigure, 'Aligned folder not found. Please align images again.', 'Error');
                return;
            end
            
            try
                % Call differenceHighlightGUI with aligned folder path
                differenceHighlightGUI(app.AlignedFolderPath);
            catch ME
                uialert(app.UIFigure, ['Error opening Difference Highlight viewer: ' ME.message], 'Error');
            end
        end

        % Button pushed function: HeatmapButton
        function HeatmapButtonPushed(app, event)
            if isempty(app.AlignedFolderPath)
                uialert(app.UIFigure, 'Please align images first!', 'Error');
                return;
            end
            
            % Check if aligned folder exists
            if ~exist(app.AlignedFolderPath, 'dir')
                uialert(app.UIFigure, 'Aligned folder not found. Please align images again.', 'Error');
                return;
            end
            
            try
                % Call HeatmapGUI with aligned folder path
                HeatmapGUI(app.AlignedFolderPath);
            catch ME
                uialert(app.UIFigure, ['Error opening Heatmap viewer: ' ME.message], 'Error');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            % Stop playback
            stopPlayback(app);
            
            % Stop and delete rotation timer BEFORE initializing earth globe
            if ~isempty(app.RotationTimer) && isvalid(app.RotationTimer)
                stop(app.RotationTimer);
                delete(app.RotationTimer);
                app.RotationTimer = [];
            end
            
            % Reset zoom if needed
            if app.ZoomEnabled
                zoom(app.UIAxes, 'off');
                app.ZoomEnabled = false;
                app.SmartZoomButton.Text = 'zoom in/out';
                app.SmartZoomButton.BackgroundColor = [0.4784 0.349 0.502];
            end
            
            % Clear all data
            app.CurrentImages = {};
            app.CurrentTimestamps = [];
            app.CurrentImageIndex = 0;
            app.SelectedFolderPath = "";
            app.AlignedImages = {};
            app.AlignedFolderPath = "";
            app.Tforms = {};
            app.AlignStatuses = [];
            app.PreprocessedImages = {};
            app.PreprocessedFolderPath = "";

            % Reset moon-specific properties
            app.IsMoonView = false;
            app.MoonImages = {};
            app.MoonTimestamps = [];
            app.MoonFolderPath = '';
            
            % Reset rotation properties
            app.IsRotating = false;
            app.LastMousePos = [];
            
            % Clear location markers
            app.LocationMarkers = [];
            
            % Reset text area
            app.ImagenameTextArea.Value = {'      Image Path '};
            
            % Disable all processing buttons
            app.PreviousButton.Enable = "off";
            app.NextButton.Enable = "off";
            app.PreprocessButton.Enable = "off";
            app.AlignImagesButton.Enable = "off";
            app.BlendedImageButton.Enable = "off";
            app.AlignVisualButton.Enable = "off";
            app.HeatmapButton.Enable = "off";
            app.DifferenceHighlightButton.Enable = "off";
            app.SelectAreaButton.Enable = 'off';
            app.TimelineViewerUsed = false;
            app.BlendedImageUsed = false;
                    
            % Reset time slider
            app.TimeSlider.Value = 0;
            app.PlaybackSpeed = 0;
    
            % Return to globe view
            app.IsGlobeView = true;
            initializeEarthGlobe(app);

            app.SelectImageButton.Text = 'Select Image';
            app.SelectImageButton.BackgroundColor = [231/255, 111/255, 81/255];

        end

        % Button pushed function: TipsButton
        function TipsButtonPushed(app, event)
            message = [...
                '<div class="container">' ...
                '<h1>🛰️ Satellite Imagery Change Detection App Help Dialog</h1>' ...
                '<h2>📌 Workflow Overview</h2>' ...
                '<h3>1. Select Images</h3>' ...
                '<ul>' ...
                '<li>📍 Click globe markers to load pre-defined datasets</li>' ...
                '<li>🌕 Click <b>MOON</b> to load lunar images</li>' ...
                '<li>🗂️ Use <b>Select Image</b> to choose a custom folder</li>' ...
                '</ul>' ...
                '<h3>2. Preprocess Images</h3>' ...
                '<ul>' ...
                '<li>⚙️ <b>Auto</b>: Automatic scene detection + automatic mode selection — Automatically ' ...
                'switches between retinex, brightness-only, or skip mode based on the image set</li>' ...
                '<li>🧰 <b>Basic</b>: General image enhancement — Applies histogram equalization (histeq) to each color channel</li>' ...
                '<li>✨ <b>Retinex</b>: Enhanced image enhancement — Uses the Retinex algorithm</li>' ...
                '<li>🔆 <b>Brightness-only</b>: For dark images — Applies adaptive histogram equalization (adapthisteq) to each color channel</li>' ...
                '<li>⏭️ <b>Skip</b>: No enhancement applied — Only performs bilateral filtering and cropping</li>' ...
                '</ul>' ...
                '<h3>3. Align Images</h3>' ...
                '<ul>' ...
                '<li>📐 Automatically align all images to the first image</li>' ...
                '<li>✅ Required for visualization tools</li>' ...
                '</ul>' ...
                '<h3>4. Visualization Options</h3>' ...
                '<ul>' ...
                '<li><i>AlignVisual</i>: View alignment quality</li>' ...
                '<li><i>Blended Image</i>: Overlay time periods</li>' ...
                '<li><i>Heatmap</i>: Change intensity map (requires alignment)</li>' ...
                '<li><i>Difference Highlight</i>: Highlight changes</li>' ...
                '</ul>' ...
                '<h3>5. Analysis Tools</h3>' ...
                '<ul>' ...
                '<li><i>Smart Zoom</i>: Smart zoom with selection</li>' ...
                '<li><i>Time Slider</i>: Animate time series</li>' ...
                '<li><i>Select Area</i>: Focus on regions</li>' ...
                '</ul>' ...
                '<h2>💡 Tips</h2>' ...
                '<ul>' ...
                '<li>🌀 Drag to rotate the globe</li>' ...
                '<li>⏮️⏭️ Use Previous/Next to browse</li>' ...
                '<li>🧹 Clear Cache to free memory</li>' ...
                '<li>🔄 Reset returns to globe view</li>' ...
                '</ul>' ...
                '</div>' ...
                ];
        
                % Create dark-themed help window
                figWidth = 600;
                figHeight = 720;
                
                fig = uifigure('Name', 'How to Use ChronoGlobe', ...
                               'Color', [30, 30, 47]/255, ...
                               'Resize', 'off');
                
                centerWindow(app, fig, figWidth, figHeight);
                            
                % Add formatted HTML content
                html = uihtml(fig);
                html.Position = [20, 70, 560, 630];
                html.HTMLSource = ['<html><head>' ...
                    '<style>' ...
                    'body { font-family: Segoe UI, sans-serif; background-color: #1e1e2f; color: #f0f0f0; padding: 10px; }' ...
                    'h1 { font-size: 20px; color: #ffcc70; }' ...
                    'h2 { font-size: 16px; margin-top: 20px; color: #a0c4ff; }' ...
                    'h3 { font-size: 14px; margin-top: 10px; color: #ffd6a5; }' ...
                    'ul { margin-left: 15px; }' ...
                    'li { margin-bottom: 5px; }' ...
                    'i { color: #80ffdb; }' ...
                    '</style></head><body>' ...
                    message ...
                    '</body></html>'];
            
                % Create OK button with modern styling (rounded, bright, centered)
                uibutton(fig, ...
                    'Text', '✓ OK', ...
                    'Position', [250, 20, 100, 36], ...
                    'FontWeight', 'bold', ...
                    'FontSize', 14, ...
                    'BackgroundColor', [90, 160, 255]/255, ...
                    'FontColor', 'white', ...
                    'Tooltip', 'Close this help window', ...
                    'ButtonPushedFcn', @(~,~) close(fig));

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure, ...
                'Are you sure you want to exit?', ...
                'Exit confirmation', ...
                'Options', {'Yes','No'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);
            
            if strcmp(choice,'Yes')
                % Clean up timers before closing
                if ~isempty(app.PlaybackTimer) && isvalid(app.PlaybackTimer)
                    stop(app.PlaybackTimer);
                    delete(app.PlaybackTimer);
                end
                if ~isempty(app.RotationTimer) && isvalid(app.RotationTimer)
                    stop(app.RotationTimer);
                    delete(app.RotationTimer);
                end
                delete(app);
            end
        end

        % Button pushed function: SmartZoomButton
        function SmartZoomButtonPushed(app, event)
            if ~app.ZoomEnabled
                % Enable zoom mode
                app.ZoomEnabled = true;
                app.SmartZoomButton.Text = 'Zoom Off';
                app.SmartZoomButton.BackgroundColor = [0.8, 0.2, 0.2];  % Red to indicate active
        
                % Make sure all interaction modes are off before setting callbacks
                zoom(app.UIFigure, 'off');
                pan(app.UIFigure, 'off');
                rotate3d(app.UIFigure, 'off');
                
                % Set custom click handler first
                set(app.UIFigure, 'WindowButtonDownFcn', @(src, event)startRectSelect(app));
                
                % Then enable zoom mode
                h = zoom(app.UIAxes);
                h.Enable = 'on';
                h.RightClickAction = 'InverseZoom';
     
                % Show custom-styled help dialog (inline version of showZoomHelpDialog)
                figWidth = 420;
                figHeight = 270;
                screenSize = get(0, 'ScreenSize');
                figX = (screenSize(3) - figWidth) / 2;
                figY = (screenSize(4) - figHeight) / 2;
        
                fig = uifigure('Name', 'Zoom Instructions', ...
                               'Color', [30, 30, 47]/255, ...
                               'Resize', 'off', ...
                               'Position', [figX, figY, figWidth, figHeight]);
        
                html = uihtml(fig);
                html.Position = [20, 70, figWidth - 40, figHeight - 90];
                html.HTMLSource = ['<html><body style="font-family: Segoe UI; background-color: #1e1e2f; color: #eeeeee; padding: 10px;">' ...
                    '<h2 style="color: #ffc107;">🖱️ Smart 2D Zoom Enabled</h2>' ...
                    '<ul>' ...
                    '<li>📐 <b>Draw rectangle</b> to zoom into a selected area</li>' ...
                    '<li>🖱️ <b>Left click</b>: Zoom in (×2)</li>' ...
                    '<li>🖱️ <b>Right click</b>: Zoom out (×0.5)</li>' ...
                    '<li>🖱️ <b>Double click</b>: Reset zoom</li>' ...
                    '<li>🖱️ <b>Ctrl + Scroll</b>: Fine zoom control</li>' ...
                    '</ul>' ...
                    '</body></html>'];
        
                uibutton(fig, ...
                    'Text', '✓ OK', ...
                    'Position', [figWidth/2 - 50, 20, 100, 32], ...
                    'FontWeight', 'bold', ...
                    'FontSize', 14, ...
                    'BackgroundColor', [0.2, 0.6, 1], ...
                    'FontColor', 'white', ...
                    'Tooltip', 'Close this help window', ...
                    'ButtonPushedFcn', @(~,~) close(fig));
        
            else
                % Disable zoom mode
                zoom(app.UIAxes, 'off');
                app.ZoomEnabled = false;
                app.SmartZoomButton.Text = 'Zoom in/out';
                app.SmartZoomButton.BackgroundColor = [0.4784 0.349 0.502];  % Reset to original color
        
                % Restore default interaction
                set(app.UIFigure, 'WindowButtonDownFcn', @(src, event)startRotation(app));
        
                % Reset axis mode
                axis(app.UIAxes, 'image');
            end
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = app.TimeSlider.Value;
    
            % Stop existing timer if running
            if ~isempty(app.PlaybackTimer) && isvalid(app.PlaybackTimer)
                stop(app.PlaybackTimer);
                delete(app.PlaybackTimer);
            end
            
            % If slider value is 0, stop playback
            if value == 0
                app.PlaybackSpeed = 0;
                return;
            end
            
            % Check if there are images to play
            if isempty(app.CurrentImages) || length(app.CurrentImages) < 2
                app.TimeSlider.Value = 0;
                uialert(app.UIFigure, 'Need at least 2 images to play!', 'Error');
                return;
            end
            
            % Set playback speed (higher slider value = shorter interval = faster playback)
            app.PlaybackSpeed = value;

            % Define discrete speed levels with safe intervals
            speedLevels = [
                % value_min, value_max, interval_seconds
                0,   20,  2.000;    % 0.5 fps
                20,  40,  1.000;    % 1 fps
                40,  60,  0.500;    % 2 fps
                60,  70,  0.200;    % 5 fps
                70,  80,  0.100;    % 10 fps
                80,  90,  0.050;    % 20 fps
                90,  100, 0.033;    % 30 fps (safe value: 33ms)
            ];
            
            % Find appropriate interval based on slider value
            interval = 2.0; % default
            for i = 1:size(speedLevels, 1)
                if value > speedLevels(i, 1) && value <= speedLevels(i, 2)
                    interval = speedLevels(i, 3);
                    break;
                end
            end
            
            % Ensure interval is at least 30ms for stability
            interval = max(0.03, interval);
            
            % Round to nearest millisecond to avoid any precision issues
            interval = round(interval * 1000) / 1000;
                    
            % Create and start timer
            app.PlaybackTimer = timer('ExecutionMode', 'fixedRate', ...
                                      'Period', interval, ...
                                      'TimerFcn', @(~,~)playNextImage(app));
            start(app.PlaybackTimer);
        end

        % Button pushed function: SelectAreaButton
        function SelectAreaButtonPushed(app, event)
            if isempty(app.AlignedFolderPath)
                uialert(app.UIFigure, ...
                    'Please align images first! Area detection requires aligned images.', ...
                    'Aligned Images Required');
                return;
            end
            
            if ~exist(app.AlignedFolderPath, 'dir')
                uialert(app.UIFigure, ...
                    'Aligned folder not found. Please align images again.', ...
                    'Folder Not Found');
                return;
            end
            
            try
                % Get current location name
                locationName = 'Unknown';
                
                % Check if this is a moon image
                if app.IsMoonView
                    locationName = 'Moon';
                else
                    % Extract location name from selected folder path
                    [~, folderName] = fileparts(app.SelectedFolderPath);
                    if ~isempty(folderName)
                        locationName = folderName;
                    end
                end
            
                % Create a new folder name with location name and timestamp
                timestamp = string(datetime('now', 'Format', 'yyyyMMdd_HHmmss'));
                newFolderName = sprintf('%s_%s', locationName, timestamp);
            
                % Create a new folder under .cache
                cacheDir = fullfile(pwd, '.cache');
                if ~exist(cacheDir, 'dir')
                    mkdir(cacheDir);
                end
            
                newAlignedPath = fullfile(cacheDir, newFolderName);
            
                % Copy aligned images to the new folder
                copyfile(app.AlignedFolderPath, newAlignedPath);
            
                % Launch areaDetectionGUI using the new path
                areaDetectionGUI(newAlignedPath);
            
                % Optional: display success message
                fprintf('Area detection launched with folder: %s\n', newAlignedPath);
            
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Error opening Area Detection:\n%s', ME.message), ...
                    'Error');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.SelectImageButton = uibutton(app.GridLayout4, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Icon = fullfile(pathToMLAPP, 'icon', 'select.svg');
            app.SelectImageButton.Text = 'Select Image';
            app.ImagenameTextArea = uitextarea(app.GridLayout4);
            app.ImagenameTextArea.Value = {'      Image Path '};
            app.PreprocessButton = uibutton(app.GridLayout4, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessButtonPushed, true);
            app.PreprocessButton.Icon = fullfile(pathToMLAPP, 'icon', 'preprocess.svg');
            app.PreprocessButton.Text = 'Preprocess';
            app.AlignImagesButton = uibutton(app.GridLayout4, 'push');
            app.AlignImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AlignImagesButtonPushed, true);
            app.AlignImagesButton.Icon = fullfile(pathToMLAPP, 'icon', 'image.svg');
            app.AlignImagesButton.Text = 'Align Images';
            app.ClearCacheButton = uibutton(app.GridLayout4, 'push');
            app.ClearCacheButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCacheButtonPushed, true);
            app.ClearCacheButton.Icon = fullfile(pathToMLAPP, 'icon', 'clear.svg');
            app.ClearCacheButton.Text = 'Clear Cache';
            app.GridLayout10 = uigridlayout(app.GridLayout3);
            app.VisualizationLabel = uilabel(app.GridLayout10);
            app.VisualizationLabel.Text = 'Visualization';
            app.AlignVisualButton = uibutton(app.GridLayout10, 'push');
            app.AlignVisualButton.ButtonPushedFcn = createCallbackFcn(app, @AlignVisualButtonPushed, true);
            app.AlignVisualButton.Icon = fullfile(pathToMLAPP, 'icon', 'timeline.svg');
            app.AlignVisualButton.Text = 'Align Visual';
            app.BlendedImageButton = uibutton(app.GridLayout10, 'push');
            app.BlendedImageButton.ButtonPushedFcn = createCallbackFcn(app, @BlendedImageButtonPushed, true);
            app.BlendedImageButton.Icon = fullfile(pathToMLAPP, 'icon', 'blend.svg');
            app.BlendedImageButton.Text = 'Blended Image';
            app.HeatmapButton = uibutton(app.GridLayout10, 'push');
            app.HeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @HeatmapButtonPushed, true);
            app.HeatmapButton.Icon = fullfile(pathToMLAPP, 'icon', 'heat.svg');
            app.HeatmapButton.Text = 'Heatmap';
            app.DifferenceHighlightButton = uibutton(app.GridLayout10, 'push');
            app.DifferenceHighlightButton.ButtonPushedFcn = createCallbackFcn(app, @DifferenceHighlightButtonPushed, true);
            app.DifferenceHighlightButton.Icon = fullfile(pathToMLAPP, 'icon', 'DH.svg');
            app.DifferenceHighlightButton.Text = 'Difference Highlight';
            app.GridLayout11 = uigridlayout(app.GridLayout3);
            app.InteractiveToolsLabel = uilabel(app.GridLayout11);
            app.InteractiveToolsLabel.Text = 'Interactive Tools';
            app.SmartZoomButton = uibutton(app.GridLayout11, 'push');
            app.SmartZoomButton.ButtonPushedFcn = createCallbackFcn(app, @SmartZoomButtonPushed, true);
            app.SmartZoomButton.Icon = fullfile(pathToMLAPP, 'icon', 'zoom-in.svg');
            app.SmartZoomButton.Text = 'Smart Zoom';
            app.SelectAreaButton = uibutton(app.GridLayout11, 'push');
            app.SelectAreaButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAreaButtonPushed, true);
            app.SelectAreaButton.Icon = fullfile(pathToMLAPP, 'icon', 'area.svg');
            app.SelectAreaButton.Text = 'Select Area';
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.TimeSliderLabel = uilabel(app.GridLayout12);
            app.TimeSliderLabel.Text = 'Time Slider';
            app.TimeSlider = uislider(app.GridLayout12);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.UIAxes = uiaxes(app.GridLayout6);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.ExitButton = uibutton(app.GridLayout7, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = fullfile(pathToMLAPP, 'icon', 'exit.svg');
            app.ExitButton.Text = 'Exit';
            app.TipsButton = uibutton(app.GridLayout7, 'push');
            app.TipsButton.ButtonPushedFcn = createCallbackFcn(app, @TipsButtonPushed, true);
            app.TipsButton.Icon = fullfile(pathToMLAPP, 'icon', 'help-circle.svg');
            app.TipsButton.Text = 'Tips';
            app.ResetButton = uibutton(app.GridLayout7, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'icon', 'reset.svg');
            app.ResetButton.Text = 'Reset';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.PreviousButton = uibutton(app.GridLayout8, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Icon = fullfile(pathToMLAPP, 'icon', 'left.svg');
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.GridLayout8, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Icon = fullfile(pathToMLAPP, 'icon', 'next.svg');
            app.NextButton.Text = 'Next';
            app.SatelliteImageryChangeDetectionAppLabel = uilabel(app.GridLayout);
            app.SatelliteImageryChangeDetectionAppLabel.Text = 'Satellite Imagery Change Detection App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end